classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ImportAudioFileButton  matlab.ui.control.Button
        AttackEditFieldLabel   matlab.ui.control.Label
        AttackEditField        matlab.ui.control.NumericEditField
        DecayEditFieldLabel    matlab.ui.control.Label
        DecayEditField         matlab.ui.control.NumericEditField
        SustainEditFieldLabel  matlab.ui.control.Label
        SustainEditField       matlab.ui.control.NumericEditField
        ReleaseEditFieldLabel  matlab.ui.control.Label
        ReleaseEditField       matlab.ui.control.NumericEditField
        Label                  matlab.ui.control.Label
        EditField_5            matlab.ui.control.NumericEditField
        Label_2                matlab.ui.control.Label
        EditField_6            matlab.ui.control.NumericEditField
        Label_3                matlab.ui.control.Label
        EditField_7            matlab.ui.control.NumericEditField
        Label_4                matlab.ui.control.Label
        EditField_8            matlab.ui.control.NumericEditField
        PlayButton             matlab.ui.control.Button
        PauseButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportAudioFileButton
        function ImportAudioFileButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav'; '*.mp3'; '*.*'}, 'Pick an audio file');
            if ~ischar(filename); return; end

            global fullname
            fullname = fullfile(filepath, filename);
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
           global fullname;
           global Player
            try
                [y, fs] = audioread(fullname);
                Player=audioplayer(y,fs);
                play(Player)
            catch uhoh
                uiwait(msgbox('Could not open that file with audioread'));
           end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pause(Player)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportAudioFileButton = uibutton(app.UIFigure, 'push');
            app.ImportAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAudioFileButtonPushed, true);
            app.ImportAudioFileButton.Text = {'Import Audio File'; ''};
            app.AttackEditFieldLabel = uilabel(app.UIFigure);
            app.AttackEditFieldLabel.Text = 'Attack';
            app.AttackEditField = uieditfield(app.UIFigure, 'numeric');
            app.DecayEditFieldLabel = uilabel(app.UIFigure);
            app.DecayEditFieldLabel.Text = 'Decay';
            app.DecayEditField = uieditfield(app.UIFigure, 'numeric');
            app.SustainEditFieldLabel = uilabel(app.UIFigure);
            app.SustainEditFieldLabel.Text = 'Sustain';
            app.SustainEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReleaseEditFieldLabel = uilabel(app.UIFigure);
            app.ReleaseEditFieldLabel.Text = 'Release';
            app.ReleaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end